classdef FlexBenchApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        MainTab                matlab.ui.container.Tab
        ConfiguracinTab        matlab.ui.container.Tab
        ComandEditField        matlab.ui.control.EditField
        ComandEditFieldLabel   matlab.ui.control.Label
        MonitorTextArea        matlab.ui.control.TextArea
        ConnectLamp            matlab.ui.control.Lamp
        ConnectButton          matlab.ui.control.Button
        BaudRateDropDown       matlab.ui.control.DropDown
        BaudRateDropDownLabel  matlab.ui.control.Label
        PortDropDown           matlab.ui.control.DropDown
        PortDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        ESP32 % Puerto serie
    end
    
    methods (Access = private)
        
        function leerMensajeSerie(app, src, ~)
            try
                % 1. Leemos el mensaje que acaba de llegar por el puerto (src)
                nuevoMensaje = readline(src);
                
                % 2. Añadimos el mensaje como una nueva línea al Text Area
                app.MonitorTextArea.Value = [app.MonitorTextArea.Value; nuevoMensaje];
                
                % 3. Hacemos auto-scroll hacia abajo para ver siempre el último mensaje
                scroll(app.MonitorTextArea, 'bottom');
            catch
                % Ignoramos errores si el puerto se cierra de golpe
            end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BaudRateDropDown.Value = '115200';
        end

        % Drop down opening function: PortDropDown
        function PortDropDownOpening(app, event)

            puertosDisponibles = serialportlist("available");
            
            % Si encuentra algo, actualiza la lista. Si no, avisa.
            if isempty(puertosDisponibles)
                app.PortDropDown.Items = {'No connection available'};
            else
                app.PortDropDown.Items = puertosDisponibles;
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                % Leemos lo que el usuario ha seleccionado en los desplegables
                app.ESP32 = serialport(app.PortDropDown.Value, str2double(app.BaudRateDropDown.Value));
                
                % MUY IMPORTANTE: Le decimos a MATLAB que nuestros mensajes terminan con un "Enter" (CR/LF)
                configureTerminator(app.ESP32, "CR/LF");

                % Le decimos: "Cada vez que veas un terminator (CR/LF), ejecuta leerMensajeSerie"
                    configureCallback(app.ESP32, "terminator", @app.leerMensajeSerie);
                
                % Opcional: Cambiamos la luz a verde y el texto del botón
                app.ConnectLamp.Color = 'green';
                app.ConnectButton.Text = 'Connected';
                app.ConnectButton.Enable = 'off'; % Desactivamos el botón para no conectar 2 veces
                
                
            catch ME
                % Si algo falla (ej. el puerto está en uso), sacamos una alerta
                uialert(app.UIFigure, "Error al conectar: " + ME.message, "Error de Conexión");
                app.ConnectLamp.Color = 'red';
                app.ConnectButton.Text = 'Error in connection';
            end
        end

        % Value changed function: ComandEditField
        function ComandEditFieldValueChanged(app, event)
            % 1. Leemos lo que has escrito y lo pasamos a mayúsculas por seguridad
            textoEscrito = upper(app.ComandEditField.Value);
            
            % 2. Verificamos que estamos conectados antes de enviar nada
            if isempty(app.ESP32) || ~isvalid(app.ESP32)
                uialert(app.UIFigure, "Conecta el puerto primero.", "Error");
                return;
            end
            
            % 3. ENVIAMOS EL COMANDO AL ESP32
            writeline(app.ESP32, textoEscrito);
            
            % 4. Mostramos en nuestro monitor lo que acabamos de enviar
            % Le pongo un "> " delante para diferenciar lo que enviamos de lo que recibimos
            app.MonitorTextArea.Value = [app.MonitorTextArea.Value; "> " + textoEscrito];
            scroll(app.MonitorTextArea, 'bottom');
            
            % 5. Limpiamos la barra de texto para que puedas escribir el siguiente rápido
            app.ComandEditField.Value = '';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ConfiguracinTab = uitab(app.TabGroup);
            app.ConfiguracinTab.Title = 'Configuración';
            app.PortDropDownLabel = uilabel(app.ConfiguracinTab);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.ConfiguracinTab);
            app.PortDropDown.Items = {};
            app.PortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortDropDownOpening, true);
            app.PortDropDown.Placeholder = 'Avaliable Ports';
            app.PortDropDown.Value = {};
            app.BaudRateDropDownLabel = uilabel(app.ConfiguracinTab);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.ConfiguracinTab);
            app.BaudRateDropDown.Items = {'9600', '115200', '250000'};
            app.BaudRateDropDown.Placeholder = '115200';
            app.BaudRateDropDown.Value = '9600';
            app.ConnectButton = uibutton(app.ConfiguracinTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectLamp = uilamp(app.ConfiguracinTab);
            app.MonitorTextArea = uitextarea(app.ConfiguracinTab);
            app.MonitorTextArea.Editable = 'off';
            app.MonitorTextArea.Value = {'CONSOLE:'};
            app.ComandEditFieldLabel = uilabel(app.ConfiguracinTab);
            app.ComandEditFieldLabel.Text = 'Comand';
            app.ComandEditField = uieditfield(app.ConfiguracinTab, 'text');
            app.ComandEditField.ValueChangedFcn = createCallbackFcn(app, @ComandEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlexBenchApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end